classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        AnalyzeTab                    matlab.ui.container.Tab
        RESULTSLabel                  matlab.ui.control.Label
        PlantLeafHealthAnalyzerLabel  matlab.ui.control.Label
        AdviceTextArea                matlab.ui.control.TextArea
        StatusLabel                   matlab.ui.control.Label
        ConfidenceLabel               matlab.ui.control.Label
        ResultLabel                   matlab.ui.control.Label
        TodayyyisDatePicker           matlab.ui.control.DatePicker
        TodayyyisDatePickerLabel      matlab.ui.control.Label
        UploadButton                  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        BatchDemoTab                  matlab.ui.container.Tab
        BatchAnalysisDemoManagementLabel  matlab.ui.control.Label
        TodayyyisDatePicker_2         matlab.ui.control.DatePicker
        TodayyyisDatePicker_2Label    matlab.ui.control.Label
        ClearDemoHistoryButton        matlab.ui.control.Button
        ReadyLabel                    matlab.ui.control.Label
        GenerateDemoButton            matlab.ui.control.Button
        BatchAnalyzeButton            matlab.ui.control.Button
        StatisticsTab                 matlab.ui.container.Tab
        TodayyyisDatePicker_3         matlab.ui.control.DatePicker
        TodayyyisDatePicker_3Label    matlab.ui.control.Label
        ModelPerformanceStatisticsLabel  matlab.ui.control.Label
        StatisticsAxes                matlab.ui.control.UIAxes
        AboutTab                      matlab.ui.container.Tab
        WelcometoPlantLeafHealthAnalyzerThisapplicationisdesignedtohelpyouquicklyanalyzeplantleafimagesandansweronesimplequestionIsthisleafhealthyordoesitshowsignsofdiseaseorpestdamageThesystemworksbyusingadeeplearningmodeltrainedonplantleafimagesWhenyouselectanimageorafolderthemodelanalyzesvisualpatternsontheleafsurfaceandproducesapredictionalongwithconfidenceinformationOurvisionissimpleearlydetectioninagriculturemeanslesslossBykeepingtheprocessfastclearandeasytousethistoolaimstosupportbetterdecisionsusingjustanimageHowtheApplicationWorksAnalyzeTabSelectasingleplantleafimageThemodelprocessestheimageanddisplaysthepredictedclassandconfidencescoreBatchDemoTabSelectafoldercontainingmultipleimagesorganizedashealthypestTheapplicationevaluatestheentiresetcomputesaccuracyandgeneratesaconfusionmatrixDetailedresultsandsummariesareautomaticallysavedforlateruseStatisticsTabLoadsresultsfrompreviouslyanalyzeddatasetsVisualizesmodelperformanceacrossdifferenttestsetstohelpyoubetterunderstandconsistencyandtrendsLabel  matlab.ui.control.Label
    end

properties (Access = private)
     net
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         S = load('C:\Users\LENOVO\Desktop\matlab_final_2\plantLeafDiseaseNet.mat');
app.net = S.trainedNet;
app.TodayyyisDatePicker.Value = datetime("today");
app.UploadButton.ButtonPushedFcn = @(src,event) UploadButtonPushed(app,event);
app.TodayyyisDatePicker_2.Value = datetime("today");
app.TodayyyisDatePicker_3.Value = datetime("today");
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
           [file, path] = uigetfile({'*.jpg;*.jpeg;*.png','Image Files'});
    if isequal(file,0)
        return;
    end

    img = imread(fullfile(path,file));
    imshow(img,'Parent',app.UIAxes);
% Network input size (ResNet18 = [224 224 3])
inSize = app.net.Layers(1).InputSize;

% RGB garanti
if size(img,3) == 1
    img = cat(3, img, img, img);
end

% Resize (224x224)
imgR = imresize(img, inSize(1:2));

% Classify
[label, scores] = classify(app.net, imgR);

% Confidence (0–100)
conf = max(scores) * 100;

% Prediction
app.ResultLabel.Text = "Prediction: " + string(label);

% Confidence text
app.ConfidenceLabel.Text = sprintf("Confidence: %.1f%%", conf);

% Status + color
if contains(lower(string(label)), "healthy")
    app.StatusLabel.Text = "Status: Healthy";
    app.StatusLabel.FontColor = [0.20 0.60 0.30];   % YEŞİL
else
    app.StatusLabel.Text = "Status: Diseased";
    app.StatusLabel.FontColor = [0.65 0.18 0.18];   % KIRMIZI
end


    % ÖNERİYİ BURADA ÇAĞIRIYORUZ
    app.AdviceTextArea.Value = app.getAdvice(string(label));

        end
        function advice = getAdvice(app, lbl)

    switch lbl
        case "Tomato_Early_blight"
            advice = "Tomato Early blight: Remove infected leaves, avoid overhead watering, improve airflow.";
        case "Potato_Early_blight"
            advice = "Potato Early blight: Remove infected foliage, increase airflow, consider fungicide.";
        case "Tomato_Bacterial_spot"
            advice = "Tomato Bacterial spot: Remove infected leaves, reduce leaf wetness, copper-based treatments may help.";
        case "Pepper_Bacterial_spot"
            advice = "Pepper Bacterial spot: Remove infected leaves, avoid overhead irrigation, copper-based treatments may help.";
        otherwise
            advice = "Healthy: Maintain regular monitoring, balanced watering, and good ventilation.";
    end

        end

        % Button pushed function: BatchAnalyzeButton
        function BatchAnalyzeButtonPushed(app, event)

    rootDir = "C:\Users\LENOVO\Desktop\BatchAndDemo";
    if ~isfolder(rootDir)
        app.ReadyLabel.Text = "ERROR: rootDir not found.";
        return;
    end

    % 2) setleri bul
    d = dir(fullfile(rootDir,"set*"));
    d = d([d.isdir]);
    if isempty(d)
        app.ReadyLabel.Text = "ERROR: No set folders found.";
        return;
    end

    setNames = string({d.name});

    % 3) set seçtir
    [idx, ok] = listdlg( ...
        "PromptString","Pick a set to analyze:", ...
        "ListString",cellstr(setNames), ...
        "SelectionMode","single");

    if ~ok
        app.ReadyLabel.Text = "Cancelled.";
        return;
    end

    setDir = fullfile(rootDir, setNames(idx));
    app.ReadyLabel.Text = "Selected: " + setNames(idx);
    drawnow;

    % 4) model kontrol
    if isempty(app.net)
        app.ReadyLabel.Text = "ERROR: Model not loaded.";
        return;
    end

    % 5) datastore
    inSize = app.net.Layers(1).InputSize(1:2);
    imds = imageDatastore(setDir, ...
        "IncludeSubfolders",true, ...
        "LabelSource","foldernames");

    % 6) classify
    aug = augmentedImageDatastore(inSize, imds);
    pred = classify(app.net, aug);
    trueLab = imds.Labels;


    % TRUE label (folder based)
    trueBin = strings(numel(trueLab),1);
    trueBin(trueLab == "healthy") = "healthy";
    trueBin(trueLab ~= "healthy") = "pest";
    trueBin = categorical(trueBin, ["healthy","pest"]);

    % PRED label (model output based)
    predStr = string(pred);
    predBin = strings(numel(predStr),1);
    predBin(contains(predStr,"healthy","IgnoreCase",true)) = "healthy";
    predBin(~contains(predStr,"healthy","IgnoreCase",true)) = "pest";
    predBin = categorical(predBin, ["healthy","pest"]);

    % =====================================================
    % 8) METRICS
    % =====================================================
    acc = mean(predBin == trueBin);
    cm  = confusionmat(trueBin, predBin, ...
        "Order",categorical(["healthy","pest"]));
    wrongIdx = find(predBin ~= trueBin);

    % 9) sonuç yaz
    app.ReadyLabel.Text = sprintf( ...
        "SET: %s\nImages: %d\nAccuracy: %.2f %%\n\nConfusion Matrix\n[TP=%d  FN=%d;\n FP=%d  TN=%d]\n\nWrong: %d", ...
        setNames(idx), numel(imds.Files), acc*100, ...
        cm(1,1), cm(1,2), cm(2,1), cm(2,2), numel(wrongIdx));
% --- Set bazlı CSV kaydı ---
csvFile = fullfile(rootDir, setNames(idx) + ".csv");   % örn: ...\BatchAndDemo\set3.csv


% 1) Özet satır (summary) – tek satır
summaryRow = table( ...
    setNames(idx), ...
    numel(imds.Files), ...
    acc*100, ...
    cm(1,1), cm(1,2), cm(2,1), cm(2,2), ...
    'VariableNames', {'Set','Images','Accuracy','TP','FN','FP','TN'});

% 2) Detay tablo (her dosya için) – çok satır
fileNames = string(imds.Files);
detailTable = table( ...
    fileNames, ...
    string(trueBin), ...
    string(predBin), ...
    'VariableNames', {'File','True','Pred'});

% 3) Aynı CSV’ye hem summary hem detay yazmak
% CSV tek başına 2 tablo desteklemez; o yüzden iki dosya daha temiz:
summaryFile = fullfile(rootDir, setNames(idx) + "_summary.csv");
detailFile  = fullfile(rootDir, setNames(idx) + "_details.csv");

writetable(summaryRow, summaryFile);
writetable(detailTable, detailFile);

app.ReadyLabel.Text = "Saved: " + setNames(idx) + "_summary.csv and _details.csv ✔";


        end

        % Button pushed function: GenerateDemoButton
        function GenerateDemoButtonPushed(app, event)


    rootDir = "C:\Users\LENOVO\Desktop\BatchAndDemo";
    if ~isfolder(rootDir)
        app.ReadyLabel.Text = "ERROR: rootDir not found.";
        return;
    end

    files = dir(fullfile(rootDir, "set*_summary.csv"));
    if isempty(files)
        app.ReadyLabel.Text = "No summary CSV found. Run Batch Analyze first.";
        return;
    end

    % CSV'leri oku birleştir
    T = table();
    for k = 1:numel(files)
        fp = fullfile(rootDir, files(k).name);
        Tk = readtable(fp);
        T = [T; Tk]; %#ok<AGROW>
    end

    % set adına göre sırala
    if any(strcmp(T.Properties.VariableNames,"Set"))
        T = sortrows(T, "Set");
    end

    % -------- Batch&Demo tab: ekrana history yaz (kısa) --------
    txt = "DEMO HISTORY" + newline + newline;
    for i = 1:height(T)
        txt = txt + sprintf("%s | Img:%d | Acc:%.2f%% | TP:%d FN:%d FP:%d TN:%d\n", ...
            string(T.Set(i)), T.Images(i), T.Accuracy(i), T.TP(i), T.FN(i), T.FP(i), T.TN(i));
    end
    app.ReadyLabel.Text = txt;

  ax = app.StatisticsAxes;
cla(ax, "reset");   % tamamen sıfırla (önceki ayarlar kalmasın)
hold(ax, "on");

% --- Veri ---
vals = double(T.Accuracy);
setStr = string(T.Set);

% Accuracy 0-1 mi, 0-100 mü? (otomatik)
if ~isempty(vals) && max(vals) <= 1.01
    vals = vals * 100;
end
vals = max(0, min(100, vals));  % güvenlik: 0-100'e kırp

N = numel(vals);
x = 1:N;

% --- Bar plot (X: 1..N) ---
b = bar(ax, x, vals);
b.FaceColor = [0.25 0.25 0.25];
b.EdgeColor = "none";
b.FaceAlpha = 0.90;

% --- Eksen ayarları ---
ylim(ax, [0 100]);
xlim(ax, [0.5 N + 0.5]);

ax.XTick = x;
ax.XTickLabel = setStr;     % set isimleri görünsün
ax.XTickLabelRotation = 0;

ylabel(ax, "Accuracy (%)");
title(ax, "Accuracy per Set", "FontWeight", "bold");

ax.YGrid = "on";
ax.Box = "off";
ax.FontName = "Helvetica";
ax.FontSize = 12;
ax.TickLabelInterpreter = "none";

% X label istemiyorsan kapat
xlabel(ax, "");

% --- Bar üstüne değer yazdır ---
for i = 1:N
    y = vals(i);

    % Yazı üstten taşmasın diye konum ayarı
    yText = min(y + 1, 99);    % 100'e yakınsa 99'a çek
    text(ax, x(i), yText, sprintf("%.1f%%", y), ...
        "HorizontalAlignment", "center", ...
        "VerticalAlignment", "bottom", ...
        "FontSize", 11);
end

hold(ax, "off");


        end

        % Callback function
        function UploadButtonPushed2(app, event)
            
        end

        % Button pushed function: ClearDemoHistoryButton
        function ClearDemoHistoryButtonPushed(app, event)
            % Button pushed function: ClearDemoHistoryButton

    % Demo history (Label) temizle
    app.ReadyLabel.Text = "Ready!";    

    % Arkada biriken history varsa temizle (opsiyonel)
    if isprop(app, "DemoHistory")
        app.DemoHistory = {};
    end


        end

        % Callback function
        function ThemeSwitchValueChanged(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnalyzeTab = uitab(app.TabGroup);
            app.AnalyzeTab.Title = 'Analyze';
            app.UIAxes = uiaxes(app.AnalyzeTab);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[]'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[]'};
            app.UploadButton = uibutton(app.AnalyzeTab, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Load Image';
            app.TodayyyisDatePickerLabel = uilabel(app.AnalyzeTab);
            app.TodayyyisDatePickerLabel.Text = 'Todayyy is:';
            app.TodayyyisDatePicker = uidatepicker(app.AnalyzeTab);
            app.TodayyyisDatePicker.Value = datetime([2025 12 29]);
            app.ResultLabel = uilabel(app.AnalyzeTab);
            app.ResultLabel.Text = 'Prediction: ...';
            app.ConfidenceLabel = uilabel(app.AnalyzeTab);
            app.ConfidenceLabel.Text = 'Confidence: ...';
            app.StatusLabel = uilabel(app.AnalyzeTab);
            app.StatusLabel.Text = 'Status: ...';
            app.AdviceTextArea = uitextarea(app.AnalyzeTab);
            app.AdviceTextArea.Editable = 'off';
            app.PlantLeafHealthAnalyzerLabel = uilabel(app.AnalyzeTab);
            app.PlantLeafHealthAnalyzerLabel.Text = '  🌿Plant Leaf Health Analyzer 🌿';
            app.RESULTSLabel = uilabel(app.AnalyzeTab);
            app.RESULTSLabel.Text = 'RESULTS';
            app.BatchDemoTab = uitab(app.TabGroup);
            app.BatchDemoTab.Title = 'Batch & Demo';
            app.BatchAnalyzeButton = uibutton(app.BatchDemoTab, 'push');
            app.BatchAnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @BatchAnalyzeButtonPushed, true);
            app.BatchAnalyzeButton.Text = 'Batch Analyze Folder';
            app.GenerateDemoButton = uibutton(app.BatchDemoTab, 'push');
            app.GenerateDemoButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDemoButtonPushed, true);
            app.GenerateDemoButton.Text = 'Generate Demo History';
            app.ReadyLabel = uilabel(app.BatchDemoTab);
            app.ReadyLabel.Text = 'Ready!';
            app.ClearDemoHistoryButton = uibutton(app.BatchDemoTab, 'push');
            app.ClearDemoHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDemoHistoryButtonPushed, true);
            app.ClearDemoHistoryButton.Text = 'Clear Demo History';
            app.TodayyyisDatePicker_2Label = uilabel(app.BatchDemoTab);
            app.TodayyyisDatePicker_2Label.Text = 'Todayyy is: ';
            app.TodayyyisDatePicker_2 = uidatepicker(app.BatchDemoTab);
            app.BatchAnalysisDemoManagementLabel = uilabel(app.BatchDemoTab);
            app.BatchAnalysisDemoManagementLabel.Text = '🍃Batch Analysis & Demo Management🍃';
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            app.StatisticsAxes = uiaxes(app.StatisticsTab);
            title(app.StatisticsAxes, 'Accuracy per Set')
            xlabel(app.StatisticsAxes, 'Test Set')
            ylabel(app.StatisticsAxes, 'Accuracy (%)')
            app.StatisticsAxes.XTick = [];
            app.StatisticsAxes.YTickLabel = '';
            app.ModelPerformanceStatisticsLabel = uilabel(app.StatisticsTab);
            app.ModelPerformanceStatisticsLabel.Text = {'Model Performance Statistics🌱'; ''};
            app.TodayyyisDatePicker_3Label = uilabel(app.StatisticsTab);
            app.TodayyyisDatePicker_3Label.Text = 'Todayyy is:';
            app.TodayyyisDatePicker_3 = uidatepicker(app.StatisticsTab);
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.WelcometoPlantLeafHealthAnalyzerThisapplicationisdesignedtohelpyouquicklyanalyzeplantleafimagesandansweronesimplequestionIsthisleafhealthyordoesitshowsignsofdiseaseorpestdamageThesystemworksbyusingadeeplearningmodeltrainedonplantleafimagesWhenyouselectanimageorafolderthemodelanalyzesvisualpatternsontheleafsurfaceandproducesapredictionalongwithconfidenceinformationOurvisionissimpleearlydetectioninagriculturemeanslesslossBykeepingtheprocessfastclearandeasytousethistoolaimstosupportbetterdecisionsusingjustanimageHowtheApplicationWorksAnalyzeTabSelectasingleplantleafimageThemodelprocessestheimageanddisplaysthepredictedclassandconfidencescoreBatchDemoTabSelectafoldercontainingmultipleimagesorganizedashealthypestTheapplicationevaluatestheentiresetcomputesaccuracyandgeneratesaconfusionmatrixDetailedresultsandsummariesareautomaticallysavedforlateruseStatisticsTabLoadsresultsfrompreviouslyanalyzeddatasetsVisualizesmodelperformanceacrossdifferenttestsetstohelpyoubetterunderstandconsistencyandtrendsLabel = uilabel(app.AboutTab);
            app.WelcometoPlantLeafHealthAnalyzerThisapplicationisdesignedtohelpyouquicklyanalyzeplantleafimagesandansweronesimplequestionIsthisleafhealthyordoesitshowsignsofdiseaseorpestdamageThesystemworksbyusingadeeplearningmodeltrainedonplantleafimagesWhenyouselectanimageorafolderthemodelanalyzesvisualpatternsontheleafsurfaceandproducesapredictionalongwithconfidenceinformationOurvisionissimpleearlydetectioninagriculturemeanslesslossBykeepingtheprocessfastclearandeasytousethistoolaimstosupportbetterdecisionsusingjustanimageHowtheApplicationWorksAnalyzeTabSelectasingleplantleafimageThemodelprocessestheimageanddisplaysthepredictedclassandconfidencescoreBatchDemoTabSelectafoldercontainingmultipleimagesorganizedashealthypestTheapplicationevaluatestheentiresetcomputesaccuracyandgeneratesaconfusionmatrixDetailedresultsandsummariesareautomaticallysavedforlateruseStatisticsTabLoadsresultsfrompreviouslyanalyzeddatasetsVisualizesmodelperformanceacrossdifferenttestsetstohelpyoubetterunderstandconsistencyandtrendsLabel.Text = {'Welcome to Plant Leaf Health Analyzer! 🌿'; ''; 'This application is designed to help you quickly analyze plant leaf images and answer one simple question:'; ''; 'Is this leaf healthy, or does it show signs of disease or pest damage?'; 'The system works by using a deep learning model trained on plant leaf images. When you select an image or a folder, the model analyzes visual patterns on the leaf surface and produces a prediction along with confidence information.'; ''; 'Our vision is simple:'; 'early detection in agriculture means less loss.'; 'By keeping the process fast, clear, and easy to use, this tool aims to support better decisions using just an image.'; 'How the Application Works'; 'Analyze Tab'; 'Select a single plant leaf image.'; 'The model processes the image and displays the predicted class and confidence score.'; 'Batch & Demo Tab'; 'Select a folder containing multiple images (organized as healthy / pest).'; 'The application evaluates the entire set, computes accuracy, and generates a confusion matrix.'; 'Detailed results and summaries are automatically saved for later use.'; 'Statistics Tab:'; 'Loads results from previously analyzed datasets.'; 'Visualizes model performance across different test sets to help you better understand consistency and trends.'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end